classdef EnergyDispersionRelation2DGraphite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        energyDispersion  matlab.ui.control.UIAxes
        aSliderLabel      matlab.ui.control.Label
        aSlider           matlab.ui.control.Slider
        e2pSliderLabel    matlab.ui.control.Label
        e2pSlider         matlab.ui.control.Slider
        sSliderLabel      matlab.ui.control.Label
        sSlider           matlab.ui.control.Slider
        tSliderLabel      matlab.ui.control.Label
        tSlider           matlab.ui.control.Slider
        ResetButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        a;
        e2p;
        s;
        t;        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.energyDispersion.cla;
            app.aSlider.Value = 1;
            app.e2pSlider.Value = 0;
            app.tSlider.Value = -3.033;
            app.sSlider.Value = 0.129;
            app.a = app.aSlider.Value;
            app.e2p = app.e2pSlider.Value;
            app.t = app.tSlider.Value;
            app.s = app.sSlider.Value;
            
            k_x = -sqrt(3)*pi/app.a :0.1: sqrt(3)*pi/app.a;
            k_y = -pi:0.1:pi;
            for i = 1:length(k_y)
                for j = 1:length(k_x)
                    omega = sqrt(1+4*cos(sqrt(3)*k_x(j)*app.a/2)*cos(k_y(i)*app.a/2)+ 4*cos(k_y(i)*app.a/2)*cos(k_y(i)*app.a/2));
                    Eg2Dp (i,j) = (app.e2p + app.t*omega)/(1 + app.s*omega);
                    Eg2Dn (i,j) = (app.e2p - app.t*omega)/(1 - app.s*omega);
                end
            end
            surf(app.energyDispersion, k_x, k_y,  Eg2Dp);
            hold(app.energyDispersion, 'on');
            surf(app.energyDispersion, k_x, k_y, Eg2Dn);
            xlabel (app.energyDispersion, 'k_x');
            ylabel (app.energyDispersion, 'k_y');
            zlabel (app.energyDispersion, 'E');
            zlab = get(app.energyDispersion,'ZLabel');
            set(zlab, 'Rotation', 0,'VerticalAlignment','middle')
            colorbar(app.energyDispersion);
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            app.a = event.Value;
            k_x = -sqrt(3)*pi/app.a :0.1: sqrt(3)*pi/app.a;
            k_y = -pi:0.1:pi;
            for i = 1:length(k_y)
                for j = 1:length(k_x)
                    omega = sqrt(1+4*cos(sqrt(3)*k_x(j)*app.a/2)*cos(k_y(i)*app.a/2)+ 4*cos(k_y(i)*app.a/2)*cos(k_y(i)*app.a/2));
                    Eg2Dp (i,j) = (app.e2p + app.t*omega)/(1 + app.s*omega);
                    Eg2Dn (i,j) = (app.e2p - app.t*omega)/(1 - app.s*omega);
                end
            end
            surf(app.energyDispersion, k_x, k_y,  Eg2Dp);
            hold(app.energyDispersion, 'on');
            surf(app.energyDispersion, k_x, k_y, Eg2Dn);
            colorbar(app.energyDispersion);
        end

        % Value changing function: e2pSlider
        function e2pSliderValueChanging(app, event)
            app.e2p = event.Value;
            k_x = -sqrt(3)*pi/app.a :0.1: sqrt(3)*pi/app.a;
            k_y = -pi:0.1:pi;
            for i = 1:length(k_y)
                for j = 1:length(k_x)
                    omega = sqrt(1+4*cos(sqrt(3)*k_x(j)*app.a/2)*cos(k_y(i)*app.a/2)+ 4*cos(k_y(i)*app.a/2)*cos(k_y(i)*app.a/2));
                    Eg2Dp (i,j) = (app.e2p + app.t*omega)/(1 + app.s*omega);
                    Eg2Dn (i,j) = (app.e2p - app.t*omega)/(1 - app.s*omega);
                end
            end
            surf(app.energyDispersion, k_x, k_y,  Eg2Dp);
            hold(app.energyDispersion, 'on');
            surf(app.energyDispersion, k_x, k_y, Eg2Dn);
            colorbar(app.energyDispersion);
        end

        % Value changing function: sSlider
        function sSliderValueChanging(app, event)
            app.s = event.Value;
            k_x = -sqrt(3)*pi/app.a :0.1: sqrt(3)*pi/app.a;
            k_y = -pi:0.1:pi;
            for i = 1:length(k_y)
                for j = 1:length(k_x)
                    omega = sqrt(1+4*cos(sqrt(3)*k_x(j)*app.a/2)*cos(k_y(i)*app.a/2)+ 4*cos(k_y(i)*app.a/2)*cos(k_y(i)*app.a/2));
                    Eg2Dp (i,j) = (app.e2p + app.t*omega)/(1 + app.s*omega);
                    Eg2Dn (i,j) = (app.e2p - app.t*omega)/(1 - app.s*omega);
                end
            end
            surf(app.energyDispersion, k_x, k_y,  Eg2Dp);
            hold(app.energyDispersion, 'on');
            surf(app.energyDispersion, k_x, k_y, Eg2Dn);
            colorbar(app.energyDispersion);
        end

        % Value changing function: tSlider
        function tSliderValueChanging(app, event)
            app.t = event.Value;
            k_x = -sqrt(3)*pi/app.a :0.1: sqrt(3)*pi/app.a;
            k_y = -pi:0.1:pi;
            for i = 1:length(k_y)
                for j = 1:length(k_x)
                    omega = sqrt(1+4*cos(sqrt(3)*k_x(j)*app.a/2)*cos(k_y(i)*app.a/2)+ 4*cos(k_y(i)*app.a/2)*cos(k_y(i)*app.a/2));
                    Eg2Dp (i,j) = (app.e2p + app.t*omega)/(1 + app.s*omega);
                    Eg2Dn (i,j) = (app.e2p - app.t*omega)/(1 - app.s*omega);
                end
            end
            surf(app.energyDispersion, k_x, k_y,  Eg2Dp);
            hold(app.energyDispersion, 'on');
            surf(app.energyDispersion, k_x, k_y, Eg2Dn);
            colorbar(app.energyDispersion);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.energyDispersion = uiaxes(app.UIFigure);
            title(app.energyDispersion, 'Energy dispersion relation')
            xlabel(app.energyDispersion, 'X')
            ylabel(app.energyDispersion, 'Y')
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [0 1];
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aSlider.Value = 1;
            app.e2pSliderLabel = uilabel(app.UIFigure);
            app.e2pSliderLabel.Text = 'e2p';
            app.e2pSlider = uislider(app.UIFigure);
            app.e2pSlider.Limits = [0 1];
            app.e2pSlider.ValueChangingFcn = createCallbackFcn(app, @e2pSliderValueChanging, true);
            app.sSliderLabel = uilabel(app.UIFigure);
            app.sSliderLabel.Text = 's';
            app.sSlider = uislider(app.UIFigure);
            app.sSlider.Limits = [0 1];
            app.sSlider.ValueChangingFcn = createCallbackFcn(app, @sSliderValueChanging, true);
            app.sSlider.Value = 0.129;
            app.tSliderLabel = uilabel(app.UIFigure);
            app.tSliderLabel.Text = 't';
            app.tSlider = uislider(app.UIFigure);
            app.tSlider.Limits = [-5 5];
            app.tSlider.ValueChangingFcn = createCallbackFcn(app, @tSliderValueChanging, true);
            app.tSlider.Value = -3.033;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnergyDispersionRelation2DGraphite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end